classdef test_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        TextArea                 matlab.ui.control.TextArea
        TextAreaLabel            matlab.ui.control.Label
        ToggleLEDButton          matlab.ui.control.Button
        COMPortDropDown          matlab.ui.control.DropDown
        COMPortDropDownLabel     matlab.ui.control.Label
        ConnectSwitch            matlab.ui.control.Switch
        TabGroup                 matlab.ui.container.TabGroup
        PIDTab                   matlab.ui.container.Tab
        SendPIDParametersButton  matlab.ui.control.Button
        PID_KpTable              matlab.ui.control.Table
        PID_KiTable              matlab.ui.control.Table
        PID_KdTable              matlab.ui.control.Table
        GC_PIDTab                matlab.ui.container.Tab
    end

    properties (Access = private)
        serialObj % Serial object for handling USART communication
    end
    
    methods (Access = private)
        
        function EnableButtons(app)
            app.ToggleLEDButton.Enable = "on";
            app.SendPIDParametersButton.Enable = "on";

            app.COMPortDropDown.Enable = "off";
        end
        
        function DisableButtons(app)
            app.ToggleLEDButton.Enable = "off";
            app.SendPIDParametersButton.Enable = "off";

            app.COMPortDropDown.Enable = "on";
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            DisableButtons(app);
            
            comPorts = serialportlist("available");  
            
            if isempty(comPorts) % Set dropdown options
                app.COMPortDropDown.Items = {"No COM Ports Found"};
            else
                app.COMPortDropDown.Items = comPorts;
            end
            
            % Load previous controller parameters
            if exist('mat_data\PID_matrices.mat', 'file')
                data = load('mat_data\PID_matrices.mat');
                app.PID_KpTable.Data = data.pid_kp;
                app.PID_KiTable.Data = data.pid_ki;
                app.PID_KdTable.Data = data.pid_kd;
            else
                app.PID_KpTable.Data = zeros([4,4]);
                app.PID_KiTable.Data = zeros([4,4]);
                app.PID_KdTable.Data = zeros([4,4]);
            end
        end

        % Button pushed function: ToggleLEDButton
        function ToggleLEDButtonPushed(app, event)
            writeline(app.serialObj, "AA"); % Send command to toggle LED
        end

        % Value changed function: ConnectSwitch
        function ConnectSwitchValueChanged(app, event)
            COMvalue = app.ConnectSwitch.Value;
            if strcmp(COMvalue, "On")
                selectedPort = string(app.COMPortDropDown.Value); 
                
                if selectedPort == "No COM Ports Found" || selectedPort == ""
                    uialert(app.UIFigure, "No valid COM port selected!", "Error");
                    app.ConnectSwitch.Value = "Off";
                    return;
                end

                if ~isempty(app.serialObj) % 🛑 Close any previous connections
                    delete(app.serialObj);
                    app.serialObj = [];
                    pause(1);  % Wait a bit before reopening
                end

                try % ✅ Try to open port
                    app.serialObj = serialport(selectedPort, 115200, "Timeout", 2);

                    configureTerminator(app.serialObj, "LF");
                    configureCallback(app.serialObj, "terminator", @(src,~) processSerialData(src, app));

                    disp("Successfully opened port: " + selectedPort);
                    
                    % Enable all usuable buttons now
                    app.ConnectSwitch.Value = "On";
                    EnableButtons(app);
                catch ME
                    disp("ERROR: " + ME.message);
                    uialert(app.UIFigure, "Failed to open COM port! " + ME.message, "Error");
                    app.ConnectSwitch.Value = "Off"; 
                end
            else              
                app.ConnectSwitch.Value = "Off"; % 🛑 Disconnect
                if ~isempty(app.serialObj)
                    delete(app.serialObj);
                    app.serialObj = [];
                end
                
                % Disable unusable buttons at this stage
                DisableButtons(app);
            end
        end

        % Button pushed function: SendPIDParametersButton
        function SendPIDParametersButtonPushed(app, event)
            pid_kp = app.PID_KpTable.Data;
            pid_ki = app.PID_KiTable.Data;
            pid_kd = app.PID_KdTable.Data;
            save('mat_data\PID_matrices.mat', 'pid_kp', 'pid_ki', 'pid_kd');

            success = sendPIDParameters(app.serialObj, pid_kp, pid_ki, pid_kd);
            % if success
            %     % app.TextArea.Text = 'PID parameters updated successfully';
            % else
            %     app.TextArea.Text = 'Failed to update PID parameters';
            % end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.PIDTab = uitab(app.TabGroup);
            app.PIDTab.Title = 'PID';
            app.PID_KdTable = uitable(app.PIDTab);
            app.PID_KdTable.ColumnName = {'Kd1'; 'Kd2'; 'Kd3'; 'Kd4'};
            app.PID_KdTable.RowName = {};
            app.PID_KdTable.ColumnEditable = true;
            app.PID_KiTable = uitable(app.PIDTab);
            app.PID_KiTable.ColumnName = {'Ki1'; 'Ki2'; 'Ki3'; 'Ki4'};
            app.PID_KiTable.RowName = {};
            app.PID_KiTable.ColumnEditable = true;
            app.PID_KpTable = uitable(app.PIDTab);
            app.PID_KpTable.ColumnName = {'Kp1'; 'Kp2'; 'Kp3'; 'Kp4'};
            app.PID_KpTable.RowName = {};
            app.PID_KpTable.ColumnEditable = true;
            app.SendPIDParametersButton = uibutton(app.PIDTab, 'push');
            app.SendPIDParametersButton.ButtonPushedFcn = createCallbackFcn(app, @SendPIDParametersButtonPushed, true);
            app.SendPIDParametersButton.Text = 'Send PID Parameters';
            app.GC_PIDTab = uitab(app.TabGroup);
            app.GC_PIDTab.Title = 'GC_PID';
            app.ConnectSwitch = uiswitch(app.UIFigure, 'slider');
            app.ConnectSwitch.ValueChangedFcn = createCallbackFcn(app, @ConnectSwitchValueChanged, true);
            app.COMPortDropDownLabel = uilabel(app.UIFigure);
            app.COMPortDropDownLabel.Text = 'COM Port';
            app.COMPortDropDown = uidropdown(app.UIFigure);
            app.COMPortDropDown.Items = {};
            app.COMPortDropDown.Value = {};
            app.ToggleLEDButton = uibutton(app.UIFigure, 'push');
            app.ToggleLEDButton.ButtonPushedFcn = createCallbackFcn(app, @ToggleLEDButtonPushed, true);
            app.ToggleLEDButton.Text = 'Toggle LED';
            app.TextAreaLabel = uilabel(app.UIFigure);
            app.TextAreaLabel.Text = 'Text Area';
            app.TextArea = uitextarea(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = test_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end